classdef GenerateAnimate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        CheckBox                        matlab.ui.control.CheckBox
        ExperimentalmarkersLabel        matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        OsteoarticularsegmentmodelLabel  matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        ModelmarkersLabel               matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        ExternalforcesexperimentsLabel  matlab.ui.control.Label
        CheckBox_5                      matlab.ui.control.CheckBox
        MusclesLabel                    matlab.ui.control.Label
        CheckBox_7                      matlab.ui.control.CheckBox
        CentersofmassLabel              matlab.ui.control.Label
        LoadparametersButton            matlab.ui.control.Button
        GeneratevideoButton             matlab.ui.control.Button
        SaveparametersButton            matlab.ui.control.Button
        SavepictureButton               matlab.ui.control.Button
        CheckBox_8                      matlab.ui.control.CheckBox
        ExternalforcespredictionLabel   matlab.ui.control.Label
        CheckBox_9                      matlab.ui.control.CheckBox
        OsteoarticularbonemodelLabel    matlab.ui.control.Label
        CheckBox_10                     matlab.ui.control.CheckBox
        GlobalcenterofmassLabel         matlab.ui.control.Label
        DAnimationFigureButton          matlab.ui.control.Button
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button                          matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        EditField                       matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
    end


    properties (Access = private)
        Parameters % Description
        AnimateParameters
        num_f
        time
        Anim
    end

    methods (Access = private)
    
        function ActuatePlotModel(app)
            cla(app.UIAxes);
            app.AnimateParameters.Mode = 'GenerateAnimate';
            app.AnimateParameters.PictureFrame = app.num_f;
            app.AnimateParameters.ax = app.UIAxes;
            PlotAnimation(app.Parameters, app.AnimateParameters)
        end
        
        function ActuateFilename(app)
            app.AnimateParameters.filename = app.DropDown.Value;
            if exist([app.AnimateParameters.filename(1:end-4) '/ExperimentalData.mat'],'file')
                load([app.AnimateParameters.filename(1:end-4) '/ExperimentalData.mat']);
                app.time = ExperimentalData.Time;
                app.Slider.Limits = [0,app.time(end)];
                app.EditField.Limits = [0,app.time(end)];
            end
        end
        
        function ActuateApp(app)
            app.CheckBox_2.Value = app.AnimateParameters.seg_anim;
            app.CheckBox_5.Value = app.AnimateParameters.muscles_anim;
            app.CheckBox_3.Value = app.AnimateParameters.mod_marker_anim;
            app.CheckBox.Value = app.AnimateParameters.exp_marker_anim;
            app.CheckBox_4.Value = app.AnimateParameters.external_forces_anim;
            app.CheckBox_8.Value = app.AnimateParameters.external_forces_pred;
            app.CheckBox_9.Value = app.AnimateParameters.bone_anim;
            app.CheckBox_7.Value = app.AnimateParameters.mass_centers_anim;
            app.CheckBox_10.Value = app.AnimateParameters.Global_mass_center_anim;
            set(app.UIAxes,'view',app.AnimateParameters.view);
            set(app.UIAxes,'xlim',app.AnimateParameters.xlim);
            set(app.UIAxes,'ylim',app.AnimateParameters.ylim);
            set(app.UIAxes,'zlim',app.AnimateParameters.zlim);
        end
        
        function ActuateParameters(app)
            app.AnimateParameters.view = get(app.UIAxes,'view');
            app.AnimateParameters.xlim = get(app.UIAxes,'xlim');
            app.AnimateParameters.ylim = get(app.UIAxes,'ylim');
            app.AnimateParameters.zlim = get(app.UIAxes,'zlim');
            % Actualiser le fichier AnimateParameters à partir de l'application            
        end
        
        function LoadIK(app)
            app.Anim.ModelChoice = 0;
            load([app.AnimateParameters.filename(1:end-4) '/InverseKinematicsResults.mat']);
            q = InverseKinematicsResults.JointCoordinates;
            q6dof = InverseKinematicsResults.FreeJointCoordinates;
            QQ = [q6dof(6,app.num_f);q(2:end,app.num_f);0;q6dof(1:5,app.num_f)];
            app.Anim.q = QQ;
        end
        
        function LoadMuscles(app)
            app.Anim.ModelChoice = 0;
            load([app.AnimateParameters.filename(1:end-4) '/MuscleForcesComputationResults.mat']);
            A = MuscleForcesComputationResults.MuscleActivations;
            app.Anim.A = A(:,app.num_f);            
        end
        
        function LoadRealMarkers(app)
            app.Anim.ModelChoice = 0;
            load([app.AnimateParameters.filename(1:end-4) '/ExperimentalData.mat']);
            real_markers = ExperimentalData.MarkerPositions;
            for i = 1:numel(real_markers)
                real_markers(i).position_f = real_markers(i).position(app.num_f,:);
            end
            app.Anim.real_markers = real_markers;
        end
        
        function LoadExternalForces(app)
            app.Anim.ModelChoice = 0;
            load([app.AnimateParameters.filename(1:end-4) '/ExternalForcesComputationResults.mat']);
            external_forces = ExternalForcesComputationResults.ExternalForcesExperiments;
            app.Anim.external_forces = external_forces(app.num_f).Visual;            
        end
        
        function LoadExternalForcesPred(app)
            app.Anim.ModelChoice = 0;
            load([app.AnimateParameters.filename(1:end-4) '/ExternalForcesComputationResults.mat']);
            external_forces_pred = ExternalForcesComputationResults.ExternalForcesPrediction;
            app.Anim.external_forces_pred = external_forces_pred(app.num_f).Visual;  
        end
        
        
        function ActuateEnableButton(app)
            if ~exist([app.AnimateParameters.filename(1:end-4) '/MuscleForcesComputationResults.mat'], 'file')
                app.CheckBox_5.Enable = false;
                app.CheckBox_5.Value = false;
            else
                app.CheckBox_5.Enable = true;               
            end
            if exist([app.AnimateParameters.filename(1:end-4) '/ExternalForcesComputationResults.mat'], 'file')
                load([app.AnimateParameters.filename(1:end-4) '/ExternalForcesComputationResults.mat']);
                if ~isfield(ExternalForcesComputationResults, 'ExternalForcesExperiments')
                    app.CheckBox_4.Enable = false;
                    app.CheckBox_4.Value = false;
                else
                    app.CheckBox_4.Enable = true;
                end
                if ~isfield(ExternalForcesComputationResults, 'ExternalForcesPrediction')
                    app.CheckBox_8.Enable = false;
                    app.CheckBox_8.Value = false;
                else
                    app.CheckBox_8.Enable = true;
                end
            else
                app.CheckBox_4.Enable = false;
                app.CheckBox_4.Value = false;
                app.CheckBox_8.Enable = false;
                app.CheckBox_8.Value = false;
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            % Afficher la liste des c3d
            list_files_c3d=dir('*.c3d');
            app.DropDown.Items = {list_files_c3d.name};
            load('ModelParameters.mat'); app.Parameters = ModelParameters;
            app.ActuateFilename;
            % Valeurs initiales
            app.AnimateParameters.seg_anim = 0;
            app.AnimateParameters.muscles_anim = 0;
            app.AnimateParameters.mod_marker_anim = 0;
            app.AnimateParameters.exp_marker_anim = 0;
            app.AnimateParameters.external_forces_anim = 0;
            app.AnimateParameters.external_forces_pred = 0;
            app.AnimateParameters.bone_anim = 0;
            app.AnimateParameters.mass_centers_anim = 0;
            app.AnimateParameters.Global_mass_center_anim = 0;
            app.AnimateParameters.view = [0 1];
            app.AnimateParameters.xlim = [-0.55 0.55];
            app.AnimateParameters.ylim = [-1.2 0.9];
            app.AnimateParameters.zlim = [0 2.3];
            app.ActuateApp;
            % Frame que l'on regarde au début
            app.EditField.Value = 0;
            app.Slider.Value = 0;
            app.num_f = 1;
            % Locked buttons
            app.ActuateEnableButton;
            % Tracé
            app.Anim.ModelChoice = 0;
            app.ActuatePlotModel;
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if app.Button.Value
                rotate3d(app.UIAxes,'on');
                app.Button_2.Value = false;
                app.Button_2ValueChanged;
                app.Button_3.Value = false;
                app.Button_3ValueChanged;
            else
                rotate3d(app.UIAxes,'off');
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            if app.Button_2.Value
                zoom(app.UIAxes,'on');
                app.Button.Value = false;
                app.ButtonValueChanged;
                app.Button_3.Value = false;
                app.Button_3ValueChanged;
            else
                zoom(app.UIAxes,'off');
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            if app.Button_3.Value
                pan(app.UIAxes,'on');
                app.Button.Value = false;
                app.ButtonValueChanged;
                app.Button_2.Value = false;
                app.Button_2ValueChanged;
            else
                pan(app.UIAxes,'off');
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.ActuateFilename;
            if app.AnimateParameters.seg_anim || app.AnimateParameters.muscles_anim || app.AnimateParameters.mod_marker_anim
                app.LoadIK;
            end
            if app.AnimateParameters.muscles_anim
                app.LoadMuscles;
            end
            if app.AnimateParameters.exp_marker_anim
                app.LoadRealMarkers;
            end
            if app.AnimateParameters.external_forces_anim
                app.LoadExternalForces;
            end 
            if app.AnimateParameters.external_forces_pred
                app.LoadExternalForcesPred;
            end 
            app.ActuateEnableButton; % Locked buttons
            app.ActuatePlotModel;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.EditField.Value = round(app.EditField.Value*(1/app.time(2)))/(1/app.time(2));
            app.num_f = round(1+app.EditField.Value*(1/app.time(2)));
            app.Slider.Value = app.EditField.Value;
            if app.AnimateParameters.seg_anim || app.AnimateParameters.muscles_anim || app.AnimateParameters.mod_marker_anim
                app.LoadIK;
            end
            if app.AnimateParameters.muscles_anim
                app.LoadMuscles;
            end
            if app.AnimateParameters.exp_marker_anim
                app.LoadRealMarkers;
            end
            if app.AnimateParameters.external_forces_anim
                app.LoadExternalForces;
            end
            if app.AnimateParameters.external_forces_pred
                app.LoadExternalForcesPred;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value*(1/app.time(2)))/(1/app.time(2));
            app.num_f = round(1 + app.Slider.Value*(1/app.time(2)));
            app.EditField.Value = app.Slider.Value;
            if app.AnimateParameters.seg_anim || app.AnimateParameters.muscles_anim || app.AnimateParameters.mod_marker_anim
                app.LoadIK;
            end
            if app.AnimateParameters.muscles_anim
                app.LoadMuscles;
            end
            if app.AnimateParameters.exp_marker_anim
                app.LoadRealMarkers;
            end
            if app.AnimateParameters.external_forces_anim
                app.LoadExternalForces;
            end
            if app.AnimateParameters.external_forces_pred
                app.LoadExternalForcesPred;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if app.CheckBox_2.Value
                app.AnimateParameters.seg_anim = 1;
            else
                app.AnimateParameters.seg_anim = 0;
            end
            app.LoadIK;
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            if app.CheckBox_5.Value
                app.AnimateParameters.muscles_anim = 1;
            else
                app.AnimateParameters.muscles_anim = 0;
            end
            app.LoadIK;
            app.LoadMuscles;
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            if app.CheckBox_3.Value
                app.AnimateParameters.mod_marker_anim = 1;
            else
                app.AnimateParameters.mod_marker_anim = 0;
            end
            app.LoadIK;
            app.ActuatePlotModel;
        end

        % Button pushed function: GeneratevideoButton
        function GeneratevideoButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
            app.AnimateParameters.Mode = 'Figure';
            PlotAnimation(app.Parameters, app.AnimateParameters);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.AnimateParameters.exp_marker_anim = 1;
            else
                app.AnimateParameters.exp_marker_anim = 0;
            end
            app.LoadRealMarkers;
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            if app.CheckBox_4.Value
                app.AnimateParameters.external_forces_anim = 1;
            else
                app.AnimateParameters.external_forces_anim = 0;
            end
            app.LoadExternalForces;
            app.ActuatePlotModel;            
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            [FileNameParameters,PathNameParameters] = uigetfile('AnalysisParameters.mat','Select a Parameters file');
            OldAnimateParameters = load([PathNameParameters FileNameParameters]);
            OldAnimateParameters = OldAnimateParameters.AnimateParameters;
            % Sauvegarde
            app.AnimateParameters = OldAnimateParameters;
            app.ActuateFilename;
            if app.AnimateParameters.seg_anim || app.AnimateParameters.muscles_anim || app.AnimateParameters.mod_marker_anim
                app.LoadIK;
            end
            if app.AnimateParameters.muscles_anim
                app.LoadMuscles;
            end
            if app.AnimateParameters.exp_marker_anim
                app.LoadRealMarkers;
            end
            if app.AnimateParameters.external_forces_anim
                app.LoadExternalForces;
            end
            if app.AnimateParameters.external_forces_pred
                app.LoadExternalForcesPred;
            end
            app.ActuateApp;
            app.ActuatePlotModel;            
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
            app.AnimateParameters.Mode = 'Picture';
            app.AnimateParameters.PictureFrame = app.num_f;
            PlotAnimation(app.Parameters, app.AnimateParameters);
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            if app.CheckBox_8.Value
                app.AnimateParameters.external_forces_pred = 1;
            else
                app.AnimateParameters.external_forces_pred = 0;
            end
            app.LoadExternalForcesPred;
            app.ActuatePlotModel;
        end

        % Button pushed function: DAnimationFigureButton
        function DAnimationFigureButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
            app.AnimateParameters.Mode = 'cFigure';
            PlotAnimation(app.Parameters, app.AnimateParameters);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if app.CheckBox_7.Value
                app.AnimateParameters.mass_centers_anim = 1;
            else
                app.AnimateParameters.mass_centers_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            if app.CheckBox_10.Value
                app.AnimateParameters.Global_mass_center_anim = 1;
            else
                app.AnimateParameters.Global_mass_center_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            if app.CheckBox_9.Value
                app.AnimateParameters.bone_anim = 1;
            else
                app.AnimateParameters.bone_anim = 0;
            end
            app.ActuatePlotModel;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ExperimentalmarkersLabel = uilabel(app.Panel);
            app.ExperimentalmarkersLabel.Text = 'Experimental markers';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.OsteoarticularsegmentmodelLabel = uilabel(app.Panel);
            app.OsteoarticularsegmentmodelLabel.Text = 'Osteo-articular segment model';
            app.CheckBox_3 = uicheckbox(app.Panel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.ModelmarkersLabel = uilabel(app.Panel);
            app.ModelmarkersLabel.Text = 'Model markers';
            app.CheckBox_4 = uicheckbox(app.Panel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.ExternalforcesexperimentsLabel = uilabel(app.Panel);
            app.ExternalforcesexperimentsLabel.Text = 'External forces (experiments)';
            app.CheckBox_5 = uicheckbox(app.Panel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.MusclesLabel = uilabel(app.Panel);
            app.MusclesLabel.Text = 'Muscles';
            app.CheckBox_7 = uicheckbox(app.Panel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CentersofmassLabel = uilabel(app.Panel);
            app.CentersofmassLabel.Text = 'Centers of mass';
            app.LoadparametersButton = uibutton(app.Panel, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.GeneratevideoButton = uibutton(app.Panel, 'push');
            app.GeneratevideoButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevideoButtonPushed, true);
            app.GeneratevideoButton.Text = 'Generate video';
            app.SaveparametersButton = uibutton(app.Panel, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.SavepictureButton = uibutton(app.Panel, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.CheckBox_8 = uicheckbox(app.Panel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.ExternalforcespredictionLabel = uilabel(app.Panel);
            app.ExternalforcespredictionLabel.Text = 'External forces (prediction)';
            app.CheckBox_9 = uicheckbox(app.Panel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.OsteoarticularbonemodelLabel = uilabel(app.Panel);
            app.OsteoarticularbonemodelLabel.Text = 'Osteo-articular bone model';
            app.CheckBox_10 = uicheckbox(app.Panel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.GlobalcenterofmassLabel = uilabel(app.Panel);
            app.GlobalcenterofmassLabel.Text = 'Global center of mass';
            app.DAnimationFigureButton = uibutton(app.Panel, 'push');
            app.DAnimationFigureButton.ButtonPushedFcn = createCallbackFcn(app, @DAnimationFigureButtonPushed, true);
            app.DAnimationFigureButton.Text = '3D Animation Figure';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'Zoom.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Icon = 'Pan.png';
            app.Button_3.Text = '';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'Rotate.png';
            app.Button.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GenerateAnimate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end