classdef OptionsExternalForces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExternalforcesoptionsUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        OKButton                       matlab.ui.control.Button
        CheckBox                       matlab.ui.control.CheckBox
        ExternalforcesfilteringLabel   matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        CutofffrenquencyHzLabel        matlab.ui.control.Label
        DropDown_1                     matlab.ui.control.DropDown
        PlatformLabel_1                matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        PlatformLabel_2                matlab.ui.control.Label
        DropDown_3                     matlab.ui.control.DropDown
        PlatformLabel_3                matlab.ui.control.Label
        DropDown_4                     matlab.ui.control.DropDown
        PlatformLabel_4                matlab.ui.control.Label
        DropDown_5                     matlab.ui.control.DropDown
        PlatformLabel_5                matlab.ui.control.Label
        DropDown_6                     matlab.ui.control.DropDown
        PlatformLabel_6                matlab.ui.control.Label
        MethodtoextractdataLabel       matlab.ui.control.Label
        DropDown_7                     matlab.ui.control.DropDown
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
        num_m
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            app.CheckBox.Value = OldAnalysisParameters.ExternalForces.FilterActive;
            app.CheckBoxValueChanged;
            app.EditField.Value = OldAnalysisParameters.ExternalForces.FilterCutOff;
            app.DropDown_7.Value = func2str(OldAnalysisParameters.ExternalForces.Method);
            app.DropDown_7ValueChanged;
            for i=1:numel(OldAnalysisParameters.ExternalForces.Options)
                if strcmp(OldAnalysisParameters.ExternalForces.Options{i},'NoContact')
                    Solid = 'No contact';
                else
                    Solid = OldAnalysisParameters.ExternalForces.Options{i};
                end
                eval(['app.DropDown_' num2str(i) '.Value = Solid;'])
            end            
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.ExternalForces.FilterActive = app.CheckBox.Value;
            OldAnalysisParameters.ExternalForces.FilterCutOff = app.EditField.Value;
            OldAnalysisParameters.ExternalForces.Method = str2func(app.DropDown_7.Value);
            app.DropDown_7ValueChanged;
            OldAnalysisParameters.ExternalForces.Options = {};
            for i=1:app.num_m
                Solid = eval(['app.DropDown_' num2str(i) '.Value']);
                if strcmp(Solid,'No contact')
                    Solid = 'NoContact';
                end 
                OldAnalysisParameters.ExternalForces.Options = [OldAnalysisParameters.ExternalForces.Options;...
                    Solid];
            end
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            app.num_m = 0;
            % Initialisation des méthodes disponibles
            path_models = fileparts(which('ExternalForcesComputation'));
            list_files = dir([path_models '/FromExperiments/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.DropDown_7.Items = list_files; app.DropDown_7.ItemsData = list_files;
            % Actualisation des paramètres
            app.AnalysisParameters = OldAnalysisParameters;
            ActuateApp(app,OldAnalysisParameters);
        end

        % Close request function: ExternalforcesoptionsUIFigure
        function ExternalforcesoptionsUIFigureCloseRequest(app, event)
            EnableButton8(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.ExternalforcesoptionsUIFigureCloseRequest;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.CutofffrenquencyHzLabel.Visible = 'on';
                app.EditField.Visible = 'on';
            else
                app.CutofffrenquencyHzLabel.Visible = 'off';
                app.EditField.Visible = 'off';
            end 
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            if strcmp(app.DropDown_7.Value,'DataInC3D') || strcmp(app.DropDown_7.Value,'DataInC3D_Manutention')
                % Nombre de plateformes
                h = btkReadAcquisition(app.AnalysisParameters.filename{1});
                [Forceplates] = btkGetForcePlatforms(h);
                app.num_m = numel(Forceplates);
                for i=1:app.num_m
                    eval(['app.PlatformLabel_' num2str(i) '.Visible=''on'';'])
                    eval(['app.DropDown_' num2str(i) '.Visible=''on'';'])
                end
                for i=(app.num_m+1) : 6
                    eval(['app.PlatformLabel_' num2str(i) '.Visible=''off'';'])
                    eval(['app.DropDown_' num2str(i) '.Visible=''off'';'])
                end
            else
                for i=1:6
                    eval(['app.PlatformLabel_' num2str(i) '.Visible = ''off'';'])
                    eval(['app.DropDown_' num2str(i) '.Visible = ''off'';'])
                end
            end
            for i=1:6
                eval(['app.PlatformLabel_' num2str(i) '.Text=''Plateform ' num2str(i) ''';'])
                eval(['app.DropDown_' num2str(i) '.Items=[''No contact'' {app.Human_model.name}];'])
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExternalforcesoptionsUIFigure = uifigure;
            app.ExternalforcesoptionsUIFigure.Name = 'External forces options';
            app.ExternalforcesoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExternalforcesoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.ExternalforcesoptionsUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CheckBox = uicheckbox(app.ExternalforcesoptionsUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.ExternalforcesfilteringLabel = uilabel(app.ExternalforcesoptionsUIFigure);
            app.ExternalforcesfilteringLabel.Text = 'External forces filtering';
            app.EditField = uieditfield(app.ExternalforcesoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 5;
            app.CutofffrenquencyHzLabel = uilabel(app.ExternalforcesoptionsUIFigure);
            app.CutofffrenquencyHzLabel.Text = 'Cut-off frenquency (Hz)';
            app.DropDown_1 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_1 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_1.Text = 'Platform';
            app.DropDown_2 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_2 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_2.Text = 'Platform';
            app.DropDown_3 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_3 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_3.Text = 'Platform';
            app.DropDown_4 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_4 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_4.Text = 'Platform';
            app.DropDown_5 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_5 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_5.Text = 'Platform';
            app.DropDown_6 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_6 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_6.Text = 'Platform';
            app.MethodtoextractdataLabel = uilabel(app.ExternalforcesoptionsUIFigure);
            app.MethodtoextractdataLabel.Text = 'Method to extract data';
            app.DropDown_7 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionsExternalForces(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExternalforcesoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExternalforcesoptionsUIFigure)
        end
    end
end