classdef OptionsIK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InversekinematicsoptionsUIFigure  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ActivestepCheckBox              matlab.ui.control.CheckBox
        OKButton                        matlab.ui.control.Button
        MethodDropDown                  matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        JointcoordinatesfilteringLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        CutofffrenquencyHzLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.IK.Active;
            % Method
            app.MethodDropDown.Value = num2str(OldAnalysisParameters.IK.Method);
            % Filtre
            app.CheckBox.Value = OldAnalysisParameters.IK.FilterActive;
            app.EditField.Value = OldAnalysisParameters.IK.FilterCutOff;
            app.CheckBoxValueChanged
            % Active
            app.ActivestepCheckBoxValueChanged;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.IK.Active = app.ActivestepCheckBox.Value;
            % Frames
            OldAnalysisParameters.IK.Method = str2double(app.MethodDropDown.Value);
            % Filtre
            OldAnalysisParameters.IK.FilterActive = app.CheckBox.Value;
            OldAnalysisParameters.IK.FilterCutOff = app.EditField.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            app.MethodDropDown.ItemsData = {'1' '2'};
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: InversekinematicsoptionsUIFigure
        function InversekinematicsoptionsUIFigureCloseRequest(app, event)
            EnableButton1(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.InversekinematicsoptionsUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
          if app.ActivestepCheckBox.Value
               app.MethodDropDown.Visible = 'on';
               app.MethodLabel.Text = 'Method';
               app.CheckBox.Visible = 'on';
               app.JointcoordinatesfilteringLabel.Text = 'Joint coordinates filtering';
               app.CutofffrenquencyHzLabel.Text = 'Cut-off frequency (Hz)';
               app.CheckBoxValueChanged;
               app.EditField.Visible = 'on';
           else
               app.MethodDropDown.Visible = 'off';
               app.MethodLabel.Text = '';
               app.CheckBox.Visible = 'off';
               app.JointcoordinatesfilteringLabel.Text = '';
               app.CutofffrenquencyHzLabel.Text = '';
               app.EditField.Visible = 'off';
           end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.CutofffrenquencyHzLabel.Visible = 'on';
                app.EditField.Visible = 'on';
            else
                app.CutofffrenquencyHzLabel.Visible = 'off';
                app.EditField.Visible = 'off';
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InversekinematicsoptionsUIFigure = uifigure;
            app.InversekinematicsoptionsUIFigure.Name = 'Inverse kinematics options';
            app.InversekinematicsoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @InversekinematicsoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.InversekinematicsoptionsUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MethodDropDown = uidropdown(app.InversekinematicsoptionsUIFigure);
            app.MethodDropDown.Items = {'Optimization problem', 'Levenberg-Marquardt algorithm'};
            app.MethodDropDown.ItemsData = '12';
            app.MethodDropDown.Value = '2';
            app.MethodLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MethodLabel.Text = 'Method';
            app.CheckBox = uicheckbox(app.InversekinematicsoptionsUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.JointcoordinatesfilteringLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.JointcoordinatesfilteringLabel.Text = 'Joint coordinates filtering';
            app.EditField = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 5;
            app.CutofffrenquencyHzLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.CutofffrenquencyHzLabel.Text = 'Cut-off frenquency (Hz)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionsIK(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InversekinematicsoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InversekinematicsoptionsUIFigure)
        end
    end
end